classdef helper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        MavProxyPathButton         matlab.ui.control.Button
        ArdupilotPathButton        matlab.ui.control.Button
        EditField_ardupilot_path   matlab.ui.control.EditField
        EditField_mavproxy_path    matlab.ui.control.EditField
        OpenQGroundControlButton   matlab.ui.control.Button
        ConnectToUDOOButton        matlab.ui.control.Button
        StartSITLButton            matlab.ui.control.StateButton
        RunMavlinkproxySITLButton  matlab.ui.control.StateButton
        RunMavlinkproxyrealButton  matlab.ui.control.StateButton
        ShowMavproxyLogsButton     matlab.ui.control.Button
        ShowRunningPulsesButton    matlab.ui.control.Button
        ShowRunningLogsButton      matlab.ui.control.Button
        ShowRunningGPSButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        % path var
        ArduPilot_Path = '~/Devel/ardupilot'
        MavlinkProxy_Path = '~/Devel/TrackerBots/code/mavlink-proxy'
        
        save_folder_name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % display default path
            app.EditField_ardupilot_path.Value = app.ArduPilot_Path;
            app.EditField_mavproxy_path.Value = app.MavlinkProxy_Path;
        end

        % Button pushed function: OpenQGroundControlButton
        function OpenQGroundControlButtonPushed(app, event)
            system('qgroundcontrol &');
        end

        % Value changed function: StartSITLButton
        function StartSITLButtonValueChanged(app, event)
            value = app.StartSITLButton.Value;
            if(value) % pressed
                system(['cd ', app.ArduPilot_Path , '; xfce4-terminal -e ''zsh -c "./sim_sitl.sh ;zsh" '' ']);
            end
            
        end

        % Button pushed function: ArdupilotPathButton
        function ArdupilotPathButtonPushed(app, event)
            app.ArduPilot_Path = uigetdir();
            app.EditField_ardupilot_path.Value = app.ArduPilot_Path;
        end

        % Button pushed function: MavProxyPathButton
        function MavProxyPathButtonPushed(app, event)
            app.MavlinkProxy_Path = uigetdir();
            app.EditField_mavproxy_path.Value = app.MavlinkProxy_Path;
        end

        % Value changed function: RunMavlinkproxySITLButton
        function RunMavlinkproxySITLButtonValueChanged(app, event)
            value = app.RunMavlinkproxySITLButton.Value;
            if(value) % pressed 
                 system(['cd ', app.MavlinkProxy_Path , '; ./mavlink-proxy config_sitl.toml &']); 
            else    % released
                system('killall mavlink-proxy');
            end
        end

        % Value changed function: RunMavlinkproxyrealButton
        function RunMavlinkproxyrealButtonValueChanged(app, event)
            value = app.RunMavlinkproxyrealButton.Value;
            if(value) % pressed 
                 system(['cd ', app.MavlinkProxy_Path , '; ./mavlink-proxy config_pc.toml &>> detect_log.txt &']); 
            else    % released
                system('killall mavlink-proxy');
            end
        end

        % Button pushed function: ShowRunningLogsButton
        function ShowRunningLogsButtonPushed(app, event)
            system(['cd ', app.MavlinkProxy_Path , '; xfce4-terminal -e ''zsh -c "less +F -+S log.jsonl ;zsh" '' ']); 
        end

        % Button pushed function: ShowRunningPulsesButton
        function ShowRunningPulsesButtonPushed(app, event)
            system(['cd ', app.MavlinkProxy_Path , '; xfce4-terminal -e ''zsh -c "less +F -+S pulse_log.jsonl ;zsh" '' ']); 
        end

        % Button pushed function: ShowRunningGPSButton
        function ShowRunningGPSButtonPushed(app, event)
            system(['cd ', app.MavlinkProxy_Path , '; xfce4-terminal -e ''zsh -c "less +F -+S gps_log.jsonl ;zsh" '' ']); 
        end

        % Button pushed function: ConnectToUDOOButton
        function ConnectToUDOOButtonPushed(app, event)
            system(['xfce4-terminal -e ''zsh -c "sshpass -p ''trackerbot'' ssh trackerbot@10.42.0.1 ; zsh" '' ']);
        end

        % Button pushed function: ShowMavproxyLogsButton
        function ShowMavproxyLogsButtonPushed(app, event)
            system(['cd ', app.MavlinkProxy_Path , '; xfce4-terminal -e ''zsh -c "less +F -+S detect_log.txt ;zsh" '' ']); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MavProxyPathButton = uibutton(app.GridLayout, 'push');
            app.MavProxyPathButton.ButtonPushedFcn = createCallbackFcn(app, @MavProxyPathButtonPushed, true);
            app.MavProxyPathButton.Text = 'MavProxy Path';
            app.ArdupilotPathButton = uibutton(app.GridLayout, 'push');
            app.ArdupilotPathButton.ButtonPushedFcn = createCallbackFcn(app, @ArdupilotPathButtonPushed, true);
            app.ArdupilotPathButton.Text = 'Ardupilot Path';
            app.EditField_ardupilot_path = uieditfield(app.GridLayout, 'text');
            app.EditField_ardupilot_path.Editable = 'off';
            app.EditField_mavproxy_path = uieditfield(app.GridLayout, 'text');
            app.EditField_mavproxy_path.Editable = 'off';
            app.OpenQGroundControlButton = uibutton(app.GridLayout, 'push');
            app.OpenQGroundControlButton.ButtonPushedFcn = createCallbackFcn(app, @OpenQGroundControlButtonPushed, true);
            app.OpenQGroundControlButton.Text = 'Open QGroundControl';
            app.ConnectToUDOOButton = uibutton(app.GridLayout, 'push');
            app.ConnectToUDOOButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToUDOOButtonPushed, true);
            app.ConnectToUDOOButton.Text = 'Connect To UDOO';
            app.StartSITLButton = uibutton(app.GridLayout, 'state');
            app.StartSITLButton.ValueChangedFcn = createCallbackFcn(app, @StartSITLButtonValueChanged, true);
            app.StartSITLButton.Text = 'Start SITL';
            app.RunMavlinkproxySITLButton = uibutton(app.GridLayout, 'state');
            app.RunMavlinkproxySITLButton.ValueChangedFcn = createCallbackFcn(app, @RunMavlinkproxySITLButtonValueChanged, true);
            app.RunMavlinkproxySITLButton.Text = 'Run Mavlink-proxy SITL';
            app.RunMavlinkproxyrealButton = uibutton(app.GridLayout, 'state');
            app.RunMavlinkproxyrealButton.ValueChangedFcn = createCallbackFcn(app, @RunMavlinkproxyrealButtonValueChanged, true);
            app.RunMavlinkproxyrealButton.Text = 'Run Mavlink-proxy real';
            app.ShowMavproxyLogsButton = uibutton(app.GridLayout, 'push');
            app.ShowMavproxyLogsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMavproxyLogsButtonPushed, true);
            app.ShowMavproxyLogsButton.Text = 'Show Mav-proxy Logs';
            app.ShowRunningPulsesButton = uibutton(app.GridLayout, 'push');
            app.ShowRunningPulsesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRunningPulsesButtonPushed, true);
            app.ShowRunningPulsesButton.Text = 'Show Running Pulses';
            app.ShowRunningLogsButton = uibutton(app.GridLayout, 'push');
            app.ShowRunningLogsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRunningLogsButtonPushed, true);
            app.ShowRunningLogsButton.Text = 'Show Running Logs';
            app.ShowRunningGPSButton = uibutton(app.GridLayout, 'push');
            app.ShowRunningGPSButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRunningGPSButtonPushed, true);
            app.ShowRunningGPSButton.Text = 'Show Running GPS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end